classdef SpikeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OptionalIntervalLengthEditField  matlab.ui.control.NumericEditField
        OptionalIntervalLengthLabel  matlab.ui.control.Label
        SelectaclusterLabel          matlab.ui.control.Label
        ImportworkspaceLabel         matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        FilenameLabel                matlab.ui.control.Label
        ImportButton                 matlab.ui.control.Button
        DropDown                     matlab.ui.control.DropDown
        RightPanel                   matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Clusters              % Cell array that stores spikes from each cluster
        Rectime               % Total duration of the recording
        Interval_length = 5   % Duration of each interval
        Num_intervals         % Total number of intervals
    end
    
    
    % Helper functions
    methods (Access = private)
        
        % totalIntervals: calculates total number of intervals 
        function totalIntervals(app)
            % uses total duration and the duration of each interval
            % to calculate the total number of intervals
            app.Num_intervals = ceil(app.Rectime/app.Interval_length);
        end

        % createCellArray: prepares spike data for plotting
        function [spk_times,spks_cluster] = createCellArray(app, cluster_num)
            % create a cell array with spike times grouped into 5-second intervals
            totalIntervals(app); 
            cluster             = app.Clusters{cluster_num};       % get selected cluster from cell array
            spk_times           = cluster(:,1);                    % get spikes from the cluster   
            
            % group spike times by intervals
            for i=1:app.Num_intervals
                lower           = (i-1)*app.Interval_length;
                upper           = i*app.Interval_length;
                spks_cluster{i} = spk_times(spk_times > lower & ...
                                            spk_times <= upper) - lower;
            end
        end
        
        % plotSpikeRaster: generates a spike raster plot for the cluster
        function plotSpikeRaster(app, spks_cluster)
            % set axes ticks and limits 
            app.UIAxes.XLim          = [0 app.Interval_length];
            app.UIAxes.YLim          = [1 app.Num_intervals];
            app.UIAxes.XTick         = 0 : app.Interval_length;
            app.UIAxes.XTickLabel    = 0 : app.Interval_length;
            app.UIAxes.YTick         = 1 : app.Num_intervals;
            app.UIAxes.YTickLabel    = 1 : app.Num_intervals;

            % Plot spike times from each interval
            for i = 1:length(spks_cluster)     
                spikes           = spks_cluster{i}';   % Get all spike times for the interval   
                x            = repmat(spikes,3,1);     % Duplicate the array 
                y      	     = nan(size(x));           % Create empty array of the same size as x
                
                if ~isempty(y)
                    y(1,:)   = i;    % offset spikes for each interval by 1 unit              
                    y(2,:)   = i+1;  % along the y-axis
                end
                plot(app.UIAxes, x, y, 'Color', 'k')  % plot the interval 
                hold(app.UIAxes,'on');
            
            end
            set(app.UIAxes, 'YDir','reverse') % reverse y-axis 
            hold(app.UIAxes,'off');
        end


        %plotPSTH: generates a peristimulus time histogram for the cluster
        function plotPSTH(app, spks_cluster)
            % collect spikes from all trials
            spk = [];
            for i = 1:length(spks_cluster)
                spk  = [spk; spks_cluster{i}];            
            end
            
            % plot histogram, adjust axes parameters
            bins                     = 1000;
            app.UIAxes2.XLim         = [0  app.Interval_length];
            app.UIAxes2.XTick        = 0 : app.Interval_length;
            app.UIAxes2.XTickLabel   = 0 : app.Interval_length;
            h                        = histogram(app.UIAxes2, spk, bins);
            y_vals                   = max(h.Values) + round(max(h.Values)*.1);
            app.UIAxes2.YLim         = [0  y_vals];
            h.FaceColor              = 'black';

            % Convert spikes/bin (y-axis values) into spikes/second
            bin_length         = app.Interval_length/bins;                  % the duration of each bin
            bins_sec           = bins/app.Interval_length;                  % Number of bins/second
            new_y              = strings(length(app.UIAxes2.YTickLabel),1);
            for i = 1:length(app.UIAxes2.YTickLabel)
                spike          = str2num(app.UIAxes2.YTickLabel{i});
                spks_sec       = (spike / length(spks_cluster)) * bins_sec; % avg spikes/bin * bins/sec = avg spikes/sec
                new_y{i}       = num2str(round(spks_sec));                  % Change y-axis labels
            end

            app.UIAxes2.YTickLabel  = new_y;

        end

    
        % refreshWorkspace: clear variables when we import a new file
        function refreshWorkspace(app)
            evalin('base','clearvars *');
        end
        
        % resetAxes: resets X- and Y-axes to default parameters 
        function resetAxes(app)
            % reset raster plot axes
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.TickLength = [0.005 0.01];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100 101 102 103 104 105 106 107 108 109 110 111 112 113 114 115 116 117 118 119 120 121 122 123 124 125 126 127 128 129 130 131 132 133 134 135 136 137 138 139 140 141 142 143];
            app.UIAxes.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'; '65'; '66'; '67'; '68'; '69'; '70'; '71'; '72'; '73'; '74'; '75'; '76'; '77'; '78'; '79'; '80'; '81'; '82'; '83'; '84'; '85'; '86'; '87'; '88'; '89'; '90'; '91'; '92'; '93'; '94'; '95'; '96'; '97'; '98'; '99'; '100'; '101'; '102'; '103'; '104'; '105'; '106'; '107'; '108'; '109'; '110'; '111'; '112'; '113'; '114'; '115'; '116'; '117'; '118'; '119'; '120'; '121'; '122'; '123'; '124'; '125'; '126'; '127'; '128'; '129'; '130'; '131'; '132'; '133'; '134'; '135'; '136'; '137'; '138'; '139'; '140'; '141'; '142'; '143'};
            
            % reset histogram axes
            app.UIAxes2.XLim = [0 5];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.XTick = 0 : 5;
            app.UIAxes2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.UIAxes2.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.UIAxes2.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'; '65'; '66'; '67'; '68'; '69'; '70'; '71'; '72'; '73'; '74'; '75'; '76'; '77'; '78'; '79'; '80'; '81'; '82'; '83'; '84'; '85'; '86'; '87'; '88'; '89'; '90'; '91'; '92'; '93'; '94'; '95'; '96'; '97'; '98'; '99'; '100'};
            app.UIAxes2.ZTick = [];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % Execute when a cluster is selected
            
            %clear plots
            cla(app.UIAxes); 
            cla(app.UIAxes2);
            
            % restore axes parameters of both plots to default
            resetAxes(app);

            if app.DropDown.Value ~= 'none'
                app.Label.Text       = "Plotting..."; % show status
                drawnow
    
                cluster_num          = str2num(app.DropDown.Value);        % get the cluster number
                [~,spks_cluster]     = createCellArray(app, cluster_num);  % prepare data for plotting
                                                        
                % Plot graphs
                plotSpikeRaster(app, spks_cluster) 
                plotPSTH(app, spks_cluster)
    
                app.Label.Text        = "Done!";   % update status
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            refreshWorkspace(app);                                 % clear old variables, if any
            [filename,~]                = uigetfile();
            app.FilenameEditField.Value = filename;
            evalin('base',['load(''' filename ''')']);             % load workspace from input file
            s                           = whos('-file',filename);  % get variables from the input 

            % find the objects with 'cluster' in their names from the workspace
            matches         = startsWith({s.name}, 'cluster', 'IgnoreCase',true);
            cluster_names   = {s(matches).name};
            num_clusters    = length(cluster_names);
            app.Clusters    = cell(1, num_clusters);  % create cell array for clusters data 

            % % copy cluster data from workspace
            for i = 1:num_clusters
                app.Clusters{i}  = evalin('base', cluster_names{i}); 
            end

            app.DropDown.Items = ['none',string(1:num_clusters)]; % add cluster numbers to the dropdown menu
            app.DropDown.Value = 'none';                          % set none as default when a new file is imported
            app.Rectime        = evalin('base', 'rectime');       % get recording duration from the workspace
            
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
        end

        % Value changed function: OptionalIntervalLengthEditField
        function OptionalIntervalLengthEditFieldValueChanged(app, event)
            % modifies interval length based on user input
            value = app.OptionalIntervalLengthEditField.Value;
            if(value >= 1 & value <= 100)
                app.Interval_length = value;
                app.DropDown.Value = 'none';
            else
                app.Interval_length = 5;
                app.OptionalIntervalLengthEditField.Value = 5;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'none', '1', '2', '3', '4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'none';
            app.ImportButton = uibutton(app.LeftPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameLabel = uilabel(app.LeftPanel);
            app.FilenameLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.LeftPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.ImportworkspaceLabel = uilabel(app.LeftPanel);
            app.ImportworkspaceLabel.Text = '1. Import workspace';
            app.SelectaclusterLabel = uilabel(app.LeftPanel);
            app.SelectaclusterLabel.Text = '2. Select a cluster';
            app.OptionalIntervalLengthLabel = uilabel(app.LeftPanel);
            app.OptionalIntervalLengthLabel.Text = '(Optional) Interval Length ';
            app.OptionalIntervalLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OptionalIntervalLengthEditField.Limits = [1 100];
            app.OptionalIntervalLengthEditField.ValueChangedFcn = createCallbackFcn(app, @OptionalIntervalLengthEditFieldValueChanged, true);
            app.OptionalIntervalLengthEditField.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Spike raster plot')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Simple Spikes')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100 101 102 103 104 105 106 107 108 109 110 111 112 113 114 115 116 117 118 119 120 121 122 123 124 125 126 127 128 129 130 131 132 133 134 135 136 137 138 139 140 141 142 143];
            app.UIAxes.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'; '65'; '66'; '67'; '68'; '69'; '70'; '71'; '72'; '73'; '74'; '75'; '76'; '77'; '78'; '79'; '80'; '81'; '82'; '83'; '84'; '85'; '86'; '87'; '88'; '89'; '90'; '91'; '92'; '93'; '94'; '95'; '96'; '97'; '98'; '99'; '100'; '101'; '102'; '103'; '104'; '105'; '106'; '107'; '108'; '109'; '110'; '111'; '112'; '113'; '114'; '115'; '116'; '117'; '118'; '119'; '120'; '121'; '122'; '123'; '124'; '125'; '126'; '127'; '128'; '129'; '130'; '131'; '132'; '133'; '134'; '135'; '136'; '137'; '138'; '139'; '140'; '141'; '142'; '143'};
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Peristimulus time histogram')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Spikes/s')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 5];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.XTick = [0 1 2 3 4 5];
            app.UIAxes2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.UIAxes2.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.UIAxes2.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'; '65'; '66'; '67'; '68'; '69'; '70'; '71'; '72'; '73'; '74'; '75'; '76'; '77'; '78'; '79'; '80'; '81'; '82'; '83'; '84'; '85'; '86'; '87'; '88'; '89'; '90'; '91'; '92'; '93'; '94'; '95'; '96'; '97'; '98'; '99'; '100'};
            app.UIAxes2.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end